classdef app_robot_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DeviceAcquisitionPanel         matlab.ui.container.Panel
        STOPButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        MeasurementNameTitleLabel      matlab.ui.control.Label
        MeasurementNameLabel           matlab.ui.control.Label
        SaveNameButton                 matlab.ui.control.Button
        MeasurementnameEditField       matlab.ui.control.EditField
        MeasurementnameEditFieldLabel  matlab.ui.control.Label
        DeviceConfigurationButton      matlab.ui.control.Button
        PlanarSweepConfigurationPanel  matlab.ui.container.Panel
        MeasureBothPolarizationsCheckBox  matlab.ui.control.CheckBox
        FinishTimeResultLabel          matlab.ui.control.Label
        FinishTimesLabel               matlab.ui.control.Label
        EstimatedTimeResultLabel       matlab.ui.control.Label
        EstimatedTimesLabel            matlab.ui.control.Label
        PolarizationModeSwitch         matlab.ui.control.Switch
        PolarizationModeSwitchLabel    matlab.ui.control.Label
        MovementModeSwitch             matlab.ui.control.Switch
        MovementModeSwitchLabel        matlab.ui.control.Label
        ToggleAnimationSwitch          matlab.ui.control.Switch
        ToggleAnimationSwitchLabel     matlab.ui.control.Label
        ToggleOptitrackSwitch          matlab.ui.control.Switch
        ToggleOptitrackSwitchLabel     matlab.ui.control.Label
        PlanarSweepMenuButton          matlab.ui.control.Button
        RobotparametersPanel           matlab.ui.container.Panel
        SetparametersButton            matlab.ui.control.Button
        SpeedmmsEditField              matlab.ui.control.NumericEditField
        SpeedmmsEditField_2Label       matlab.ui.control.Label
        AccelerationmmsEditField       matlab.ui.control.NumericEditField
        AccelerationmmsEditField_2Label  matlab.ui.control.Label
        OperationModePanel             matlab.ui.container.Panel
        OperationModeButtonGroup       matlab.ui.container.ButtonGroup
        MoveButton                     matlab.ui.control.RadioButton
        SimulateMoveButton             matlab.ui.control.RadioButton
        SimulationButton               matlab.ui.control.RadioButton
        CurrentConfigurationPanel      matlab.ui.container.Panel
        CopytoManualButton             matlab.ui.control.Button
        ZCartesianValueLabel           matlab.ui.control.Label
        ZLabelPosition                 matlab.ui.control.Label
        YCartesianValueLabel           matlab.ui.control.Label
        YLabelPosition                 matlab.ui.control.Label
        XCartesianValueLabel           matlab.ui.control.Label
        XLabelPosition                 matlab.ui.control.Label
        Wrist3DegValueLabel            matlab.ui.control.Label
        Wrist2DegValueLabel            matlab.ui.control.Label
        Wrist1DegValueLabel            matlab.ui.control.Label
        ElbowDegValueLabel             matlab.ui.control.Label
        ShoulderDegValueLabel          matlab.ui.control.Label
        BaseDegValueLabel              matlab.ui.control.Label
        Wrist3LabelPosition            matlab.ui.control.Label
        Wrist2LabelPosition            matlab.ui.control.Label
        Wrist1LabelPosition            matlab.ui.control.Label
        ElbowLabelPosition             matlab.ui.control.Label
        ShoulderLabelPosition          matlab.ui.control.Label
        BaseLabelPosition              matlab.ui.control.Label
        SimulationPanel                matlab.ui.container.Panel
        EnvironmentPanel               matlab.ui.container.Panel
        EnvironmentSetupButton         matlab.ui.control.Button
        ConfigPanel                    matlab.ui.container.Panel
        PoseSetupButton                matlab.ui.control.Button
        ManualCartesianPanel           matlab.ui.container.Panel
        ButtonPlusXManual              matlab.ui.control.Button
        ButtonMinusXManual             matlab.ui.control.Button
        XManualEditField               matlab.ui.control.NumericEditField
        XManualLabel                   matlab.ui.control.Label
        ManualCartesianInfoLabel       matlab.ui.control.Label
        MovetoButtonCartesian          matlab.ui.control.Button
        ZManualEditField               matlab.ui.control.NumericEditField
        ZManualLabel                   matlab.ui.control.Label
        ButtonPlusZManual              matlab.ui.control.Button
        ButtonMinusZManual             matlab.ui.control.Button
        YManualEditField               matlab.ui.control.NumericEditField
        YManualLabel                   matlab.ui.control.Label
        ButtonPlusYManual              matlab.ui.control.Button
        ButtonMinusYManual             matlab.ui.control.Button
        ManualDegreesPanel             matlab.ui.container.Panel
        GoHomeButton                   matlab.ui.control.Button
        ButtonMinusWrist3              matlab.ui.control.Button
        ButtonMinusWrist2              matlab.ui.control.Button
        ButtonMinusWrist1              matlab.ui.control.Button
        ButtonMinusElbow               matlab.ui.control.Button
        ButtonMinusShoulder            matlab.ui.control.Button
        ButtonPlusShoulder             matlab.ui.control.Button
        BaseDegreesEditField           matlab.ui.control.NumericEditField
        MovetoButtonDegrees            matlab.ui.control.Button
        ButtonPlusWrist3               matlab.ui.control.Button
        Wrist3DegreesEditField         matlab.ui.control.NumericEditField
        Wrist3Label                    matlab.ui.control.Label
        Wrist2Label                    matlab.ui.control.Label
        ButtonPlusWrist2               matlab.ui.control.Button
        Wrist2DegreesEditField         matlab.ui.control.NumericEditField
        Wrist1Label                    matlab.ui.control.Label
        ButtonPlusWrist1               matlab.ui.control.Button
        Wrist1DegreesEditField         matlab.ui.control.NumericEditField
        ElbowLabel                     matlab.ui.control.Label
        ButtonPlusElbow                matlab.ui.control.Button
        ElbowDegreesEditField          matlab.ui.control.NumericEditField
        ShoulderLabel                  matlab.ui.control.Label
        ShoulderDegreesEditField       matlab.ui.control.NumericEditField
        BaseLabel                      matlab.ui.control.Label
        ButtonPlusBase                 matlab.ui.control.Button
        ButtonMinusBase                matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot;
        env = {};
        robotCurrentConfig;
        robotDesiredConfig; 
        robotHomeConfig; 

        operationMode; 

        acc = 0.01;
        speed = 0.02;
        robotIP = '169.254.104.1';
        robotPort = 30002;

        EnvMenuHandle;
        ConfigMenuHandle;
        SweepMenuHandle;
        DeviceMenuHandle;

        robotRTDEClient;
        planarSweepData;

        rotAnglesY = pi/2;

        devPNA;
        PNANumPoints;
        lowFreq;
        highFreq;
        IFfilterRes;
        sigPower;
        receiverPort;

        measurementName = 'default_measurement';
        measurementDirectory = 'measurements';
        toggleAnimation = false;
        toolName = 'tool0';
        movementMode = 'Movej';
        polarizationMode = 'CP';
        optitrackNatnetclient;

    end

    properties (Constant)
        robotFile = 'ur10e.urdf';
        simulationSecs = 0.01;
        validationDist = 0.1;
        toleranceErrorDegrees = 0.01;
        toleranceErrorMeters = 0.002;
        weights = [0.1 0.1 0.1 0.5 0.5 0.5];
    end
    
    methods (Access = private)
        
        function defRobotDefaultConfig(app)
            fileName = 'config_0.mat';
            fileRoute = fullfile("configurations", fileName);
            [data, status] = Utils.readFile(fileRoute);
            
            if (status == 0)
                app.robotHomeConfig = data.config.configuration;
            else
                app.robotHomeConfig = homeConfiguration(app.robot);
                app.robotHomeConfig(1).JointPosition = deg2rad(0); % base
                app.robotHomeConfig(2).JointPosition = deg2rad(-80); % shoulder
                app.robotHomeConfig(3).JointPosition = deg2rad(-90); % elbow
                app.robotHomeConfig(4).JointPosition = deg2rad(-185); % wrist 1
                app.robotHomeConfig(5).JointPosition = deg2rad(-90); % wrist 2
                app.robotHomeConfig(6).JointPosition = deg2rad(0); % wrist 3
            end
            
            app.robotCurrentConfig = app.robotHomeConfig;
            app.robotDesiredConfig = app.robotHomeConfig;

        end
        
        function loadRenderRobot(app)
            [app.robot, status, exitMsg] = Utils.loadRobot(app.robotFile);
            if (status == -1)
                uialert(app.UIFigure, exitMsg, "loadRobot failed!")
            else
                app.defRobotDefaultConfig(); 
                VisualUtils.showRobotEnvPanel(app.SimulationPanel, app.robot, app.robotCurrentConfig, app.env);
            end
        end

        function updateManualRobotDesiredConfigDeg(app)
            BaseValueDeg = app.BaseDegreesEditField.Value;
            ShoulderValueDeg = app.ShoulderDegreesEditField.Value;
            ElbowValueDeg = app.ElbowDegreesEditField.Value;
            Wrist1ValueDeg = app.Wrist1DegreesEditField.Value;
            Wrist2ValueDeg = app.Wrist2DegreesEditField.Value;
            Wrist3ValueDeg = app.Wrist3DegreesEditField.Value;

            app.robotDesiredConfig(1).JointPosition = deg2rad(BaseValueDeg);
            app.robotDesiredConfig(2).JointPosition = deg2rad(ShoulderValueDeg);
            app.robotDesiredConfig(3).JointPosition = deg2rad(ElbowValueDeg);
            app.robotDesiredConfig(4).JointPosition = deg2rad(Wrist1ValueDeg);
            app.robotDesiredConfig(5).JointPosition = deg2rad(Wrist2ValueDeg);
            app.robotDesiredConfig(6).JointPosition = deg2rad(Wrist3ValueDeg);
        end
        
        function copyCurrentConfigToManual(app)
            app.BaseDegreesEditField.Value = str2double(app.BaseDegValueLabel.Text);
            app.ShoulderDegreesEditField.Value = str2double(app.ShoulderDegValueLabel.Text);
            app.ElbowDegreesEditField.Value = str2double(app.ElbowDegValueLabel.Text);
            app.Wrist1DegreesEditField.Value = str2double(app.Wrist1DegValueLabel.Text);
            app.Wrist2DegreesEditField.Value = str2double(app.Wrist2DegValueLabel.Text);
            app.Wrist3DegreesEditField.Value = str2double(app.Wrist3DegValueLabel.Text);

            app.XManualEditField.Value = str2double(app.XCartesianValueLabel.Text);
            app.YManualEditField.Value = str2double(app.YCartesianValueLabel.Text);
            app.ZManualEditField.Value = str2double(app.ZCartesianValueLabel.Text);
        end
        
        function plotMeasurement(app, measurements, Ymat, Zmat, measurementName)

            Ny = size(Ymat,2);
            Nz = size(Ymat,1);
            Nfreq = size(measurements,2);
        
            MeasurMat = reshape(measurements, Nz, Ny, Nfreq);
        
            MeasurMat(:,1:2:end-1,:) = ...
                flipud(MeasurMat(:,1:2:end-1,:));
        
            freqIndex = 2;
        
            EMfieldComplex = MeasurMat(:,:,freqIndex);
            EMfielddB = 20*log10(abs(EMfieldComplex));
        
            xmin = min(Ymat(:));
            xmax = max(Ymat(:));
            ymin = min(Zmat(:));
            ymax = max(Zmat(:));
        
            figure('Name', measurementName);
        
            subplot(1,2,1)
        
            imagesc([xmin xmax], [ymin ymax], EMfielddB);
            set(gca,'YDir','normal');
            axis equal tight;
            colorbar;
        
            title(sprintf('%s - Amplitude (dB)', polName));
            xlabel('X [m]');
            ylabel('Y [m]');
        
            subplot(1,2,2)
        
            imagesc([xmin xmax], [ymin ymax], ...
                angle(EMfieldComplex)*180/pi);
        
            set(gca,'YDir','normal');
            axis equal tight;
            colorbar;
        
            title(sprintf('%s - Phase (deg)', polName));
            xlabel(' (m)');
            ylabel('Z (m)');
        
        end
    end
    
    methods (Access = public)

        function setPlanarSweepData(app, sweepData)
            app.planarSweepData = sweepData;
        end

        function receiveEnvFromMenu(app, newEnv)
            app.env = newEnv;
            VisualUtils.showRobotEnvPanel(app.SimulationPanel, app.robot, app.robotCurrentConfig, app.env);
        end


        function receiveConfigFromMenu(app, config)
            app.BaseDegreesEditField.Value = rad2deg(config(1).JointPosition);
            app.ShoulderDegreesEditField.Value = rad2deg(config(2).JointPosition);
            app.ElbowDegreesEditField.Value = rad2deg(config(3).JointPosition);
            app.Wrist1DegreesEditField.Value = rad2deg(config(4).JointPosition);
            app.Wrist2DegreesEditField.Value = rad2deg(config(5).JointPosition);
            app.Wrist3DegreesEditField.Value = rad2deg(config(6).JointPosition);
        end


        function updateDegreesCurrentConfig(app, currentConfig)         
            app.BaseDegValueLabel.Text = num2str(rad2deg(currentConfig(1).JointPosition));
            app.ShoulderDegValueLabel.Text = num2str(rad2deg(currentConfig(2).JointPosition));
            app.ElbowDegValueLabel.Text = num2str(rad2deg(currentConfig(3).JointPosition));
            app.Wrist1DegValueLabel.Text = num2str(rad2deg(currentConfig(4).JointPosition));
            app.Wrist2DegValueLabel.Text = num2str(rad2deg(currentConfig(5).JointPosition));
            app.Wrist3DegValueLabel.Text = num2str(rad2deg(currentConfig(6).JointPosition));
        end

        function updateCartesianCurrentConfig(app, currentConfig)
            app.robotCurrentConfig = Utils.makeValidConfig(app.robot, currentConfig);

            tform = getTransform(app.robot, app.robotCurrentConfig, 'tool0');
            position = tform(1:3,4);
            x = position(1);
            y = position(2);
            z = position(3);


            app.XCartesianValueLabel.Text = num2str(x);
            app.YCartesianValueLabel.Text = num2str(y);
            app.ZCartesianValueLabel.Text = num2str(z);
            %if (strcmp(app.operationMode, app.MoveButton.Value) ||strcmp(app.operationMode, app.SimulateMoveButton.Value))
            %    VisualUtils.showRobotEnvPanel(app.SimulationPanel, app.robot, app.robotCurrentConfig, app.env);
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 1200 820];
            %app.UIFigure.Resize = 'off';

            app.loadRenderRobot();

            app.updateDegreesCurrentConfig(app.robotCurrentConfig); 
            app.updateCartesianCurrentConfig(app.robotCurrentConfig);

            app.copyCurrentConfigToManual();

            app.operationMode = app.OperationModeButtonGroup.SelectedObject.Text;

            app.AccelerationmmsEditField.Value = app.acc * 1000;
            app.SpeedmmsEditField.Value = app.speed * 1000;

            app.MeasurementNameLabel.Text = app.measurementName;

            addpath(genpath('C:\Users\Camara\Documents\NatNet_SDK_4.0\NatNetSDK'));
        end

        % Button pushed function: ButtonMinusBase
        function ButtonMinusBasePushed(app, event)
            app.BaseDegreesEditField.Value = app.BaseDegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusBase
        function ButtonPlusBasePushed(app, event)
            app.BaseDegreesEditField.Value = app.BaseDegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusShoulder
        function ButtonMinusShoulderPushed(app, event)
            app.ShoulderDegreesEditField.Value = app.ShoulderDegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusShoulder
        function ButtonPlusShoulderPushed(app, event)
            app.ShoulderDegreesEditField.Value = app.ShoulderDegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusElbow
        function ButtonMinusElbowPushed(app, event)
            app.ElbowDegreesEditField.Value = app.ElbowDegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusElbow
        function ButtonPlusElbowPushed(app, event)
            app.ElbowDegreesEditField.Value = app.ElbowDegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist1
        function ButtonMinusWrist1Pushed(app, event)
            app.Wrist1DegreesEditField.Value = app.Wrist1DegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist1
        function ButtonPlusWrist1Pushed(app, event)
            app.Wrist1DegreesEditField.Value = app.Wrist1DegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist2
        function ButtonMinusWrist2Pushed(app, event)
            app.Wrist2DegreesEditField.Value = app.Wrist2DegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist2
        function ButtonPlusWrist2Pushed(app, event)
            app.Wrist2DegreesEditField.Value = app.Wrist2DegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist3
        function ButtonMinusWrist3Pushed(app, event)
            app.Wrist3DegreesEditField.Value = app.Wrist3DegreesEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist3
        function ButtonPlusWrist3Pushed(app, event)
            app.Wrist3DegreesEditField.Value = app.Wrist3DegreesEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusXManual
        function ButtonMinusXManualPushed(app, event)
            app.XManualEditField.Value = app.XManualEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusXManual
        function ButtonPlusXManualPushed(app, event)
            app.XManualEditField.Value = app.XManualEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusYManual
        function ButtonMinusYManualPushed(app, event)
            app.YManualEditField.Value = app.YManualEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusYManual
        function ButtonPlusYManualPushed(app, event)
            app.YManualEditField.Value = app.YManualEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusZManual
        function ButtonMinusZManualPushed(app, event)
            app.ZManualEditField.Value = app.ZManualEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusZManual
        function ButtonPlusZManualPushed(app, event)
            app.ZManualEditField.Value = app.ZManualEditField.Value + 1;
        end

        % Button pushed function: CopytoManualButton
        function CopytoManualButtonPushed(app, event)
            app.copyCurrentConfigToManual();
        end

        % Button pushed function: MovetoButtonDegrees
        function MovetoButtonDegreesPushed(app, event)
            app.updateManualRobotDesiredConfigDeg();
            
            startVector = [app.robotCurrentConfig.JointPosition];
            goalVector = [app.robotDesiredConfig.JointPosition];

            planner = MovementUtils.initializePlanner(app.robot, app.env, app.validationDist);
            [interpPath, status] = MovementUtils.resolveMovementDirectDegrees(planner, ...
                startVector, goalVector, app.UIFigure);
            
            MovementUtils.executeMovement(app, interpPath, goalVector, status, []);
        end

        % Button pushed function: MovetoButtonCartesian
        function MovetoButtonCartesianPushed(app, event)
            planner = MovementUtils.initializePlanner(app.robot, app.env, app.validationDist);

            targetX = app.XManualEditField.Value;
            targetY = app.YManualEditField.Value;
            targetZ = app.ZManualEditField.Value;

            targetPose = MovementUtils.defineIkPose(app, app.rotAnglesY, targetX, targetY, targetZ);

            [goalConfig, ~] = MovementUtils.resolveInverseKinematics(app.robot, targetPose, app.weights, app.robotCurrentConfig, app.toolName);
        
            startVector = [app.robotCurrentConfig.JointPosition];
            goalVector = [goalConfig.JointPosition];

            [interpPath, status] = MovementUtils.resolveMovementDirectDegrees(planner, ...
                startVector, goalVector, app.UIFigure);

            MovementUtils.executeMovement(app, interpPath, goalVector, status, targetPose);
        end

        % Button pushed function: SetparametersButton
        function SetparametersButtonPushed(app, event)
            app.acc = app.AccelerationmmsEditField.Value / 1000;
            app.speed = app.SpeedmmsEditField.Value / 1000;
        end

        % Button pushed function: EnvironmentSetupButton
        function EnvironmentSetupButtonPushed(app, event)
            app.EnvMenuHandle = environment_window();
            app.EnvMenuHandle.AppControlHandle = app;
        end

        % Button pushed function: PoseSetupButton
        function PoseSetupButtonPushed(app, event)
            app.ConfigMenuHandle = configuration_window();
            app.ConfigMenuHandle.AppControlHandle = app;
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            fileName = 'config_0.mat';
            fileRoute = fullfile("configurations", fileName);
            [data, status] = Utils.readFile(fileRoute);
            if (status == 0)
                app.robotHomeConfig = data.config.configuration;

                planner = MovementUtils.initializePlanner(app.robot, app.env, app.validationDist);

                startVector = [app.robotCurrentConfig.JointPosition];
                goalVector = [app.robotHomeConfig.JointPosition];

                [interpPath, status] = MovementUtils.resolveMovementDirectDegrees(planner, ...
                    startVector, goalVector, app.UIFigure);
    
                MovementUtils.executeMovement(app, interpPath, goalVector, status, []);
                app.robotCurrentConfig = app.robotHomeConfig;
            else
                msg = sprintf("ERROR: Can't read the file %s", char(fileRoute));
                uialert(app.UIFigure, msg, "File read error");
            end
        end

        % Button pushed function: PlanarSweepMenuButton
        function PlanarSweepMenuButtonPushed(app, event)
            app.SweepMenuHandle = planar_sweep_window();
            app.SweepMenuHandle.setUp(app.robot, app.robotCurrentConfig, app.env, app.validationDist, app.simulationSecs, app.operationMode, ...
                app.robotRTDEClient, app.acc, app.speed, app.robotPort, app.toleranceErrorDegrees, app.weights); 
            VisualUtils.showRobotEnvPanel(app.SweepMenuHandle.SimulationPanel, app.robot, app.robotCurrentConfig, app.env);
            app.SweepMenuHandle.AppControlHandle = app;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            try
                MovementUtils.stopRTDERobot(app.robotRTDEClient, 30003, app.acc);
                msg = sprintf("Robot stop signal sent");
                uialert(app.UIFigure, msg, "Robot stop");
            catch
                msg = sprintf("ERROR: Can't stop robot remotely. Please, do it manually");
                uialert(app.UIFigure, msg, "Robot stop error");
                return;
            end
        end

        % Button pushed function: DeviceConfigurationButton
        function DeviceConfigurationButtonPushed(app, event)
            try
                app.DeviceMenuHandle = device_app;
                app.DeviceMenuHandle.AppControlHandle = app;
            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Error opening Device Configuration', 'Icon','error');
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if isempty(app.planarSweepData)
                uialert(app.UIFigure, "Planar sweep data is not loaded", "Planar sweep error");
                return;
            end
            sweepAreaInfo.secs = app.simulationSecs;
            sweepAreaInfo.robotRTDEClient = app.robotRTDEClient;
            sweepAreaInfo.robotPort = app.robotPort;
            sweepAreaInfo.optitrackNatnetclient = app.optitrackNatnetclient;
            sweepAreaInfo.robot = app.robot;
            sweepAreaInfo.env = app.env;
            sweepAreaInfo.Ny = app.planarSweepData.Ny;
            sweepAreaInfo.Nz = app.planarSweepData.Nz;
            sweepAreaInfo.yRange = [app.planarSweepData.Ymin app.planarSweepData.Ymax];
            sweepAreaInfo.zRange = [app.planarSweepData.Zmin app.planarSweepData.Zmax];
            sweepAreaInfo.x = app.planarSweepData.Xdist;
            sweepAreaInfo.weights = app.weights;
            sweepAreaInfo.startConfig = app.robotCurrentConfig;
            sweepAreaInfo.rotAngles = [app.planarSweepData.rotX app.planarSweepData.rotY app.planarSweepData.rotZ];
            sweepAreaInfo.toolName = app.toolName;
            sweepAreaInfo.devPNA = app.devPNA;
            sweepAreaInfo.numPoints = app.PNANumPoints;

            if app.MeasureBothPolarizationsCheckBox.Value
                app.polarizationMode = "CP";
                [measurementsCP, Yrot, Zrot] = SweepAreaUtils.sweepArea(app, sweepAreaInfo);
            
                app.polarizationMode = "CX";
                [measurementsCX, ~, ~] = SweepAreaUtils.sweepArea(app, sweepAreaInfo);
            else
                [measurements, Yrot, Zrot] = SweepAreaUtils.sweepArea(app, sweepAreaInfo);
            end
            
            if (~isempty(app.MeasurementnameEditField))

                measurementDir = fullfile( ...
                    app.measurementDirectory, ...
                    "measurement_" + app.measurementName);
            
                if ~isfolder(measurementDir)
                    mkdir(measurementDir);
                end
            
                if app.MeasureBothPolarizationsCheckBox.Value
                    save(fullfile(measurementDir, ...
                        "measurements_CP_" + app.measurementName + ".mat"), ...
                        "measurementsCP");
            
                    save(fullfile(measurementDir, ...
                        "measurements_CX_" + app.measurementName + ".mat"), ...
                        "measurementsCX");
                else
                    save(fullfile(measurementDir, ...
                        "measurements_" + app.measurementName + ".mat"), ...
                        "measurements");
                end
            
                save(fullfile(measurementDir, ...
                    "Yrot_" + app.measurementName + ".mat"), ...
                    "Yrot");
            
                save(fullfile(measurementDir, ...
                    "Zrot_" + app.measurementName + ".mat"), ...
                    "Zrot");
            
                pnaParams.PNANumPoints = app.PNANumPoints;
                pnaParams.lowFreq = app.lowFreq;
                pnaParams.highFreq = app.highFreq;
                pnaParams.IFfilterRes = app.IFfilterRes;
                pnaParams.sigPower = app.sigPower;
                pnaParams.receiverPort = app.receiverPort;
            
                save(fullfile(measurementDir, ...
                    "pna_parameters.mat"), ...
                    "pnaParams");
            end
    
            if ~isempty(app.devPNA)

                Ny = length(unique(Yrot));
                Nz = length(unique(Zrot));
            
                Ymat = reshape(Yrot, Nz, Ny);
                Zmat = reshape(Zrot, Nz, Ny);
            
                Ymat(:,1:2:end-1) = flipud(Ymat(:,1:2:end-1));
                Zmat(:,1:2:end-1) = flipud(Zmat(:,1:2:end-1));
            
                if app.MeasureBothPolarizationsCheckBox.Value
                    app.plotMeasurement(measurementsCP, Ymat, Zmat, ...
                        "CP-polarization");
            
                    app.plotMeasurement(measurementsCX, Ymat, Zmat, ...
                        "CX-polarization");
                else
                    app.plotMeasurement(measurements, Ymat, Zmat, ...
                        char(app.polarizationMode));
                end
            end
        end

        % Callback function
        function ConnectOptitrackButtonPushed(app, event)
            try
                app.optitrackNatnetclient = natnet;
                app.optitrackNatnetclient.HostIP = '127.0.0.1';
                app.optitrackNatnetclient.ClientIP = '127.0.0.1';
                app.optitrackNatnetclient.ConnectionType = 'Multicast';
                app.optitrackNatnetclient.connect;
                model = app.optitrackNatnetclient.getModelDescription;
                if ( model.RigidBodyCount < 1 )
                    uialert(app.UIFigure, "Optitrack connection failed!", "Optitrack failure");
	                return
                end
                uialert(app.UIFigure, "Optitrack connection success!", "Optitrack success");
            catch ME
                uialert(app.UIFigure, "Optitrack connection failed!", "Optitrack failure");
            end
        end

        % Button pushed function: SaveNameButton
        function SaveNameButtonPushed(app, event)
            app.measurementName = app.MeasurementnameEditField.Value;

            selectedDir = uigetdir(pwd, ...
                'Select measurement folder');

            if isequal(selectedDir,0)
                return;
            end

            app.measurementDirectory = selectedDir;

            app.MeasurementNameLabel.Text = ...
            app.measurementName + " (" + selectedDir + ")";
        end

        % Selection changed function: OperationModeButtonGroup
        function OperationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperationModeButtonGroup.SelectedObject;
            app.operationMode = selectedButton.Text;

            if (strcmp(app.operationMode, app.SimulateMoveButton.Text)) || (strcmp(app.operationMode, app.MoveButton.Text))
                try
                    actualConfig(6) = struct('JointPosition', 0);

                    [app.robotRTDEClient, status] = Utils.connectRTDERobot(app.robotIP);
                    if (status == -1)
                        msg = sprintf("ERROR: Can't connect to robot IP: %s", char(app.robotIP));
                        uialert(app.UIFigure, msg, "Connection error");
                        clear app.robotRTDEClient;
                    else
                        msg = sprintf("Robot connected to robot IP: %s", char(app.robotIP));
                        uialert(app.UIFigure, msg, "Connection success");
    
                        actualPosition = readJointConfiguration(app.robotRTDEClient);
                        for i = 1:6
                            actualConfig(i).JointPosition = actualPosition(i);
                        end
                        app.updateDegreesCurrentConfig(actualConfig);
                        app.updateCartesianCurrentConfig(actualConfig);

                        VisualUtils.showRobotEnvPanel(app.SimulationPanel, app.robot, app.robotCurrentConfig, app.env);
                    end
                catch ME
                    msg = sprintf("ERROR: Can't connect to robot IP: %s", char(app.robotIP));
                    uialert(app.UIFigure, msg, "Connection error");
                    clear app.robotRTDEClient;
                    return
                end
            end
            
        end

        % Value changed function: ToggleOptitrackSwitch
        function ToggleOptitrackSwitchValueChanged(app, event)
            if (strcmp(app.ToggleOptitrackSwitch.Value, 'On'))
                try
                    app.optitrackNatnetclient = natnet;
                    app.optitrackNatnetclient.HostIP = '127.0.0.1';
                    app.optitrackNatnetclient.ClientIP = '127.0.0.1';
                    app.optitrackNatnetclient.ConnectionType = 'Multicast';
                    app.optitrackNatnetclient.connect;
                    model = app.optitrackNatnetclient.getModelDescription;
                    if ( model.RigidBodyCount < 1 )
                        uialert(app.UIFigure, "Optitrack connection failed!", "Optitrack failure");
	                    return
                    end
                    uialert(app.UIFigure, "Optitrack connection success!", "Optitrack success");
                catch ME
                    uialert(app.UIFigure, "Optitrack connection failed!", "Optitrack failure");
                end
            else
                app.optitrackNatnetclient = [];
            end
        end

        % Value changed function: ToggleAnimationSwitch
        function ToggleAnimationSwitchValueChanged(app, event)
            app.toggleAnimation = strcmp(app.ToggleAnimationSwitch.Value, 'On');
        end

        % Value changed function: MovementModeSwitch
        function MovementModeSwitchValueChanged(app, event)
            app.movementMode = app.MovementModeSwitch.Value;
        end

        % Value changed function: PolarizationModeSwitch
        function PolarizationModeSwitchValueChanged(app, event)
            app.polarizationMode = app.PolarizationModeSwitch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManualDegreesPanel = uipanel(app.UIFigure);
            app.ManualDegreesPanel.Title = '                              Manual [º]';
            app.ButtonMinusBase = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusBase.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusBasePushed, true);
            app.ButtonMinusBase.Text = '-';
            app.ButtonPlusBase = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusBase.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusBasePushed, true);
            app.ButtonPlusBase.Text = '+';
            app.BaseLabel = uilabel(app.ManualDegreesPanel);
            app.BaseLabel.Text = 'Base [º]';
            app.ShoulderDegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ShoulderLabel = uilabel(app.ManualDegreesPanel);
            app.ShoulderLabel.Text = 'Shoulder [º]';
            app.ElbowDegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ButtonPlusElbow = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusElbow.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusElbowPushed, true);
            app.ButtonPlusElbow.Text = '+';
            app.ElbowLabel = uilabel(app.ManualDegreesPanel);
            app.ElbowLabel.Text = 'Elbow [º]';
            app.Wrist1DegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ButtonPlusWrist1 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusWrist1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist1Pushed, true);
            app.ButtonPlusWrist1.Text = '+';
            app.Wrist1Label = uilabel(app.ManualDegreesPanel);
            app.Wrist1Label.Text = 'Wrist 1 [º]';
            app.Wrist2DegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ButtonPlusWrist2 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusWrist2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist2Pushed, true);
            app.ButtonPlusWrist2.Text = '+';
            app.Wrist2Label = uilabel(app.ManualDegreesPanel);
            app.Wrist2Label.Text = 'Wrist 2 [º]';
            app.Wrist3Label = uilabel(app.ManualDegreesPanel);
            app.Wrist3Label.Text = 'Wrist 3 [º]';
            app.Wrist3DegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ButtonPlusWrist3 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusWrist3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist3Pushed, true);
            app.ButtonPlusWrist3.Text = '+';
            app.MovetoButtonDegrees = uibutton(app.ManualDegreesPanel, 'push');
            app.MovetoButtonDegrees.ButtonPushedFcn = createCallbackFcn(app, @MovetoButtonDegreesPushed, true);
            app.MovetoButtonDegrees.Text = 'Move to';
            app.BaseDegreesEditField = uieditfield(app.ManualDegreesPanel, 'numeric');
            app.ButtonPlusShoulder = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonPlusShoulder.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusShoulderPushed, true);
            app.ButtonPlusShoulder.Text = '+';
            app.ButtonMinusShoulder = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusShoulder.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusShoulderPushed, true);
            app.ButtonMinusShoulder.Text = '-';
            app.ButtonMinusElbow = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusElbow.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusElbowPushed, true);
            app.ButtonMinusElbow.Text = '-';
            app.ButtonMinusWrist1 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusWrist1.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist1Pushed, true);
            app.ButtonMinusWrist1.Text = '-';
            app.ButtonMinusWrist2 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusWrist2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist2Pushed, true);
            app.ButtonMinusWrist2.Text = '-';
            app.ButtonMinusWrist3 = uibutton(app.ManualDegreesPanel, 'push');
            app.ButtonMinusWrist3.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist3Pushed, true);
            app.ButtonMinusWrist3.Text = '-';
            app.GoHomeButton = uibutton(app.ManualDegreesPanel, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.ManualCartesianPanel = uipanel(app.UIFigure);
            app.ManualCartesianPanel.Title = '                      Manual [m]';
            app.ButtonMinusYManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonMinusYManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusYManualPushed, true);
            app.ButtonMinusYManual.Text = '-';
            app.ButtonPlusYManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonPlusYManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusYManualPushed, true);
            app.ButtonPlusYManual.Text = '+';
            app.YManualLabel = uilabel(app.ManualCartesianPanel);
            app.YManualLabel.Text = 'X [m]';
            app.YManualEditField = uieditfield(app.ManualCartesianPanel, 'numeric');
            app.ButtonMinusZManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonMinusZManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusZManualPushed, true);
            app.ButtonMinusZManual.Text = '-';
            app.ButtonPlusZManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonPlusZManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusZManualPushed, true);
            app.ButtonPlusZManual.Text = '+';
            app.ZManualLabel = uilabel(app.ManualCartesianPanel);
            app.ZManualLabel.Text = 'Y [m]';
            app.ZManualEditField = uieditfield(app.ManualCartesianPanel, 'numeric');
            app.MovetoButtonCartesian = uibutton(app.ManualCartesianPanel, 'push');
            app.MovetoButtonCartesian.ButtonPushedFcn = createCallbackFcn(app, @MovetoButtonCartesianPushed, true);
            app.MovetoButtonCartesian.Text = 'Move to';
            app.ManualCartesianInfoLabel = uilabel(app.ManualCartesianPanel);
            app.ManualCartesianInfoLabel.Text = '';
            app.XManualLabel = uilabel(app.ManualCartesianPanel);
            app.XManualLabel.Text = 'Z [m]';
            app.XManualEditField = uieditfield(app.ManualCartesianPanel, 'numeric');
            app.ButtonMinusXManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonMinusXManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusXManualPushed, true);
            app.ButtonMinusXManual.Text = '-';
            app.ButtonPlusXManual = uibutton(app.ManualCartesianPanel, 'push');
            app.ButtonPlusXManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusXManualPushed, true);
            app.ButtonPlusXManual.Text = '+';
            app.ConfigPanel = uipanel(app.UIFigure);
            app.PoseSetupButton = uibutton(app.ConfigPanel, 'push');
            app.PoseSetupButton.ButtonPushedFcn = createCallbackFcn(app, @PoseSetupButtonPushed, true);
            app.PoseSetupButton.Text = 'Pose Setup';
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentSetupButton = uibutton(app.EnvironmentPanel, 'push');
            app.EnvironmentSetupButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentSetupButtonPushed, true);
            app.EnvironmentSetupButton.Text = 'Environment Setup';
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = '                                                                                           Simulation Panel';
            app.CurrentConfigurationPanel = uipanel(app.UIFigure);
            app.CurrentConfigurationPanel.Title = '                                                         Current Configuration';
            app.BaseLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.BaseLabelPosition.Text = 'Base (º)';
            app.ShoulderLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.ShoulderLabelPosition.Text = 'Shoulder (º)';
            app.ElbowLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.ElbowLabelPosition.Text = 'Elbow (º)';
            app.Wrist1LabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.Wrist1LabelPosition.Text = 'Wrist 1 (º)';
            app.Wrist2LabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.Wrist2LabelPosition.Text = 'Wrist 2 (º)';
            app.Wrist3LabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.Wrist3LabelPosition.Text = 'Wrist 3 (º)';
            app.BaseDegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.BaseDegValueLabel.Text = '';
            app.ShoulderDegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.ShoulderDegValueLabel.Text = '';
            app.ElbowDegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.ElbowDegValueLabel.Text = '';
            app.Wrist1DegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.Wrist1DegValueLabel.Text = '';
            app.Wrist2DegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.Wrist2DegValueLabel.Text = '';
            app.Wrist3DegValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.Wrist3DegValueLabel.Text = '';
            app.XLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.XLabelPosition.Text = 'X [m]';
            app.XCartesianValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.XCartesianValueLabel.Text = '';
            app.YLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.YLabelPosition.Text = 'Y [m]';
            app.YCartesianValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.YCartesianValueLabel.Text = '';
            app.ZLabelPosition = uilabel(app.CurrentConfigurationPanel);
            app.ZLabelPosition.Text = 'Z [m]';
            app.ZCartesianValueLabel = uilabel(app.CurrentConfigurationPanel);
            app.ZCartesianValueLabel.Text = '';
            app.CopytoManualButton = uibutton(app.CurrentConfigurationPanel, 'push');
            app.CopytoManualButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoManualButtonPushed, true);
            app.CopytoManualButton.Text = 'Copy to Manual';
            app.OperationModePanel = uipanel(app.UIFigure);
            app.OperationModePanel.Title = '                     Operation Mode';
            app.OperationModeButtonGroup = uibuttongroup(app.OperationModePanel);
            app.OperationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationModeButtonGroupSelectionChanged, true);
            app.SimulationButton = uiradiobutton(app.OperationModeButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.SimulateMoveButton = uiradiobutton(app.OperationModeButtonGroup);
            app.SimulateMoveButton.Text = 'Simulate & Move';
            app.MoveButton = uiradiobutton(app.OperationModeButtonGroup);
            app.MoveButton.Text = 'Move';
            app.RobotparametersPanel = uipanel(app.UIFigure);
            app.RobotparametersPanel.Title = '                  Robot parameters';
            app.AccelerationmmsEditField_2Label = uilabel(app.RobotparametersPanel);
            app.AccelerationmmsEditField_2Label.Text = 'Acceleration [mm/s]';
            app.AccelerationmmsEditField = uieditfield(app.RobotparametersPanel, 'numeric');
            app.SpeedmmsEditField_2Label = uilabel(app.RobotparametersPanel);
            app.SpeedmmsEditField_2Label.Text = 'Speed [mm/s]';
            app.SpeedmmsEditField = uieditfield(app.RobotparametersPanel, 'numeric');
            app.SetparametersButton = uibutton(app.RobotparametersPanel, 'push');
            app.SetparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetparametersButtonPushed, true);
            app.SetparametersButton.Text = 'Set parameters';
            app.PlanarSweepConfigurationPanel = uipanel(app.UIFigure);
            app.PlanarSweepConfigurationPanel.Title = '                             Planar Sweep Configuration';
            app.PlanarSweepMenuButton = uibutton(app.PlanarSweepConfigurationPanel, 'push');
            app.PlanarSweepMenuButton.ButtonPushedFcn = createCallbackFcn(app, @PlanarSweepMenuButtonPushed, true);
            app.PlanarSweepMenuButton.Text = 'Planar Sweep Menu';
            app.ToggleOptitrackSwitchLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.ToggleOptitrackSwitchLabel.Text = 'Toggle Optitrack';
            app.ToggleOptitrackSwitch = uiswitch(app.PlanarSweepConfigurationPanel, 'slider');
            app.ToggleOptitrackSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleOptitrackSwitchValueChanged, true);
            app.ToggleAnimationSwitchLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.ToggleAnimationSwitchLabel.Text = 'Toggle Animation';
            app.ToggleAnimationSwitch = uiswitch(app.PlanarSweepConfigurationPanel, 'slider');
            app.ToggleAnimationSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleAnimationSwitchValueChanged, true);
            app.MovementModeSwitchLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.MovementModeSwitchLabel.Text = 'Movement Mode';
            app.MovementModeSwitch = uiswitch(app.PlanarSweepConfigurationPanel, 'slider');
            app.MovementModeSwitch.Items = {'Movej', 'Movel'};
            app.MovementModeSwitch.ValueChangedFcn = createCallbackFcn(app, @MovementModeSwitchValueChanged, true);
            app.MovementModeSwitch.Value = 'Movej';
            app.PolarizationModeSwitchLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.PolarizationModeSwitchLabel.Text = 'Polarization Mode';
            app.PolarizationModeSwitch = uiswitch(app.PlanarSweepConfigurationPanel, 'slider');
            app.PolarizationModeSwitch.Items = {'CP', 'CX'};
            app.PolarizationModeSwitch.ValueChangedFcn = createCallbackFcn(app, @PolarizationModeSwitchValueChanged, true);
            app.PolarizationModeSwitch.Value = 'CP';
            app.EstimatedTimesLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.EstimatedTimesLabel.Text = 'Estimated Time (s):';
            app.EstimatedTimeResultLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.EstimatedTimeResultLabel.Text = '';
            app.FinishTimesLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.FinishTimesLabel.Text = 'Finish Time (s): ';
            app.FinishTimeResultLabel = uilabel(app.PlanarSweepConfigurationPanel);
            app.FinishTimeResultLabel.Text = '';
            app.MeasureBothPolarizationsCheckBox = uicheckbox(app.PlanarSweepConfigurationPanel);
            app.MeasureBothPolarizationsCheckBox.Text = 'Measure Both Polarizations';
            app.DeviceAcquisitionPanel = uipanel(app.UIFigure);
            app.DeviceAcquisitionPanel.Title = '                           Device & Acquisition';
            app.DeviceConfigurationButton = uibutton(app.DeviceAcquisitionPanel, 'push');
            app.DeviceConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @DeviceConfigurationButtonPushed, true);
            app.DeviceConfigurationButton.Text = 'Device Configuration';
            app.MeasurementnameEditFieldLabel = uilabel(app.DeviceAcquisitionPanel);
            app.MeasurementnameEditFieldLabel.Text = 'Measurement name';
            app.MeasurementnameEditField = uieditfield(app.DeviceAcquisitionPanel, 'text');
            app.SaveNameButton = uibutton(app.DeviceAcquisitionPanel, 'push');
            app.SaveNameButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNameButtonPushed, true);
            app.SaveNameButton.Text = 'Save Name';
            app.MeasurementNameLabel = uilabel(app.DeviceAcquisitionPanel);
            app.MeasurementNameLabel.Text = '';
            app.MeasurementNameTitleLabel = uilabel(app.DeviceAcquisitionPanel);
            app.MeasurementNameTitleLabel.Text = 'Measurement Name:';
            app.STARTButton = uibutton(app.DeviceAcquisitionPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.DeviceAcquisitionPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_robot_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end