classdef configuration_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PoseViewPanel             matlab.ui.container.Panel
        PoseOptionsPanel          matlab.ui.container.Panel
        ExporttoMainWindowButton  matlab.ui.control.Button
        SaveascurrentIdButton     matlab.ui.control.Button
        ButtonMinusWrist3         matlab.ui.control.Button
        ButtonMinusWrist2         matlab.ui.control.Button
        ButtonMinusWrist1         matlab.ui.control.Button
        ButtonMinusElbow          matlab.ui.control.Button
        ButtonMinusShoulder       matlab.ui.control.Button
        ButtonPlusShoulder        matlab.ui.control.Button
        BaseEditField             matlab.ui.control.NumericEditField
        MovetoButtonDegrees       matlab.ui.control.Button
        ButtonPlusWrist3          matlab.ui.control.Button
        Wrist3EditField           matlab.ui.control.NumericEditField
        Wrist3Label               matlab.ui.control.Label
        Wrist2Label               matlab.ui.control.Label
        ButtonPlusWrist2          matlab.ui.control.Button
        Wrist2EditField           matlab.ui.control.NumericEditField
        Wrist1Label               matlab.ui.control.Label
        ButtonPlusWrist1          matlab.ui.control.Button
        Wrist1EditField           matlab.ui.control.NumericEditField
        ElbowLabel                matlab.ui.control.Label
        ButtonPlusElbow           matlab.ui.control.Button
        ElbowEditField            matlab.ui.control.NumericEditField
        ShoulderLabel             matlab.ui.control.Label
        ShoulderEditField         matlab.ui.control.NumericEditField
        BaseLabel                 matlab.ui.control.Label
        ButtonPlusBase            matlab.ui.control.Button
        ButtonMinusBase           matlab.ui.control.Button
        PoseSetupPanel            matlab.ui.container.Panel
        LoadButton                matlab.ui.control.Button
        PoseIdSpinner             matlab.ui.control.Spinner
        PoseIdSpinnerLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        robot;
        selectedConfig;
        AppControlHandle;
        env;
    end
    
    properties (Access = private)
        selectedConfigId;
    end
    
    methods (Access = private)

        function defRobotDefaultConfig(app)
            app.selectedConfig = homeConfiguration(app.robot);
            app.selectedConfig(1).JointPosition = deg2rad(0); 
            app.selectedConfig(2).JointPosition = deg2rad(-80); 
            app.selectedConfig(3).JointPosition = deg2rad(-90); 
            app.selectedConfig(4).JointPosition = deg2rad(-185); 
            app.selectedConfig(5).JointPosition = deg2rad(-90); 
            app.selectedConfig(6).JointPosition = deg2rad(-90); 

        end

        function updateConfigValues(app)
            app.BaseEditField.Value = rad2deg(app.selectedConfig(1).JointPosition);
            app.ShoulderEditField.Value = rad2deg(app.selectedConfig(2).JointPosition);
            app.ElbowEditField.Value = rad2deg(app.selectedConfig(3).JointPosition);
            app.Wrist1EditField.Value = rad2deg(app.selectedConfig(4).JointPosition);
            app.Wrist2EditField.Value = rad2deg(app.selectedConfig(5).JointPosition);
            app.Wrist3EditField.Value = rad2deg(app.selectedConfig(6).JointPosition);
        end
        
        function writeConfigFile(app)
            fileName = sprintf('config_%d.mat', app.selectedConfigId);
            fileRoute = fullfile("configurations", fileName);
            config.id = app.selectedConfigId;
            config.configuration = app.selectedConfig;
            save(fileRoute, 'config');
        end

        function readConfigFile(app)
            fileName = sprintf('config_%d.mat', app.selectedConfigId);
            fileRoute = fullfile("configurations", fileName);
            [data, status] = Utils.readFile(fileRoute);
            if (status == 0)
                app.selectedConfig = data.config.configuration;
            else
                msg = sprintf("ERROR: Can't read the file %s", char(fileRoute));
                uialert(app.UIFigure, msg, "File read error");
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PoseIdSpinner.Limits = [0, 100];
            app.UIFigure.Position = [100 100 640 510];
            app.UIFigure.Resize = 'off';
        end

        % Button pushed function: ButtonMinusBase
        function ButtonMinusBasePushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusBase
        function ButtonPlusBasePushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusShoulder
        function ButtonMinusShoulderPushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusShoulder
        function ButtonPlusShoulderPushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusElbow
        function ButtonMinusElbowPushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value - 1;
        end

        % Button pushed function: ButtonPlusElbow
        function ButtonPlusElbowPushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist1
        function ButtonMinusWrist1Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist1
        function ButtonPlusWrist1Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist2
        function ButtonMinusWrist2Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist2
        function ButtonPlusWrist2Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value + 1;
        end

        % Button pushed function: ButtonMinusWrist3
        function ButtonMinusWrist3Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value - 1;
        end

        % Button pushed function: ButtonPlusWrist3
        function ButtonPlusWrist3Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value + 1;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.selectedConfigId = app.PoseIdSpinner.Value;
            app.readConfigFile();
            app.robot = app.AppControlHandle.robot;
            app.env = app.AppControlHandle.env;
            app.updateConfigValues();
            VisualUtils.showRobotEnvPanel(app.PoseViewPanel, app.robot, app.selectedConfig, app.env);
            
        end

        % Button pushed function: MovetoButtonDegrees
        function MovetoButtonDegreesPushed(app, event)
            app.selectedConfig(1).JointPosition = deg2rad(app.BaseEditField.Value);
            app.selectedConfig(2).JointPosition = deg2rad(app.ShoulderEditField.Value);
            app.selectedConfig(3).JointPosition = deg2rad(app.ElbowEditField.Value);
            app.selectedConfig(4).JointPosition = deg2rad(app.Wrist1EditField.Value);
            app.selectedConfig(5).JointPosition = deg2rad(app.Wrist2EditField.Value);
            app.selectedConfig(6).JointPosition = deg2rad(app.Wrist3EditField.Value);

            VisualUtils.showRobotEnvPanel(app.PoseViewPanel, app.robot, app.selectedConfig, app.env);
        end

        % Button pushed function: SaveascurrentIdButton
        function SaveascurrentIdButtonPushed(app, event)
            app.writeConfigFile();
        end

        % Button pushed function: ExporttoMainWindowButton
        function ExporttoMainWindowButtonPushed(app, event)
            app.AppControlHandle.receiveConfigFromMenu(app.selectedConfig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PoseSetupPanel = uipanel(app.UIFigure);
            app.PoseSetupPanel.Title = '                                                                                    Pose Setup';
            app.PoseIdSpinnerLabel = uilabel(app.PoseSetupPanel);
            app.PoseIdSpinnerLabel.Text = 'Pose Id';
            app.PoseIdSpinner = uispinner(app.PoseSetupPanel);
            app.LoadButton = uibutton(app.PoseSetupPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PoseOptionsPanel = uipanel(app.UIFigure);
            app.PoseOptionsPanel.Title = '                                 Pose Options';
            app.ButtonMinusBase = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusBase.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusBasePushed, true);
            app.ButtonMinusBase.Text = '-';
            app.ButtonPlusBase = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusBase.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusBasePushed, true);
            app.ButtonPlusBase.Text = '+';
            app.BaseLabel = uilabel(app.PoseOptionsPanel);
            app.BaseLabel.Text = 'Base [º]';
            app.ShoulderEditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ShoulderLabel = uilabel(app.PoseOptionsPanel);
            app.ShoulderLabel.Text = 'Shoulder [º]';
            app.ElbowEditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ButtonPlusElbow = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusElbow.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusElbowPushed, true);
            app.ButtonPlusElbow.Text = '+';
            app.ElbowLabel = uilabel(app.PoseOptionsPanel);
            app.ElbowLabel.Text = 'Elbow [º]';
            app.Wrist1EditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ButtonPlusWrist1 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusWrist1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist1Pushed, true);
            app.ButtonPlusWrist1.Text = '+';
            app.Wrist1Label = uilabel(app.PoseOptionsPanel);
            app.Wrist1Label.Text = 'Wrist 1 [º]';
            app.Wrist2EditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ButtonPlusWrist2 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusWrist2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist2Pushed, true);
            app.ButtonPlusWrist2.Text = '+';
            app.Wrist2Label = uilabel(app.PoseOptionsPanel);
            app.Wrist2Label.Text = 'Wrist 2 [º]';
            app.Wrist3Label = uilabel(app.PoseOptionsPanel);
            app.Wrist3Label.Text = 'Wrist 3 [º]';
            app.Wrist3EditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ButtonPlusWrist3 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusWrist3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusWrist3Pushed, true);
            app.ButtonPlusWrist3.Text = '+';
            app.MovetoButtonDegrees = uibutton(app.PoseOptionsPanel, 'push');
            app.MovetoButtonDegrees.ButtonPushedFcn = createCallbackFcn(app, @MovetoButtonDegreesPushed, true);
            app.MovetoButtonDegrees.Text = 'View';
            app.BaseEditField = uieditfield(app.PoseOptionsPanel, 'numeric');
            app.ButtonPlusShoulder = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonPlusShoulder.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusShoulderPushed, true);
            app.ButtonPlusShoulder.Text = '+';
            app.ButtonMinusShoulder = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusShoulder.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusShoulderPushed, true);
            app.ButtonMinusShoulder.Text = '-';
            app.ButtonMinusElbow = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusElbow.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusElbowPushed, true);
            app.ButtonMinusElbow.Text = '-';
            app.ButtonMinusWrist1 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusWrist1.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist1Pushed, true);
            app.ButtonMinusWrist1.Text = '-';
            app.ButtonMinusWrist2 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusWrist2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist2Pushed, true);
            app.ButtonMinusWrist2.Text = '-';
            app.ButtonMinusWrist3 = uibutton(app.PoseOptionsPanel, 'push');
            app.ButtonMinusWrist3.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusWrist3Pushed, true);
            app.ButtonMinusWrist3.Text = '-';
            app.SaveascurrentIdButton = uibutton(app.PoseOptionsPanel, 'push');
            app.SaveascurrentIdButton.ButtonPushedFcn = createCallbackFcn(app, @SaveascurrentIdButtonPushed, true);
            app.SaveascurrentIdButton.Text = 'Save as current Id';
            app.ExporttoMainWindowButton = uibutton(app.PoseOptionsPanel, 'push');
            app.ExporttoMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoMainWindowButtonPushed, true);
            app.ExporttoMainWindowButton.Text = 'Export to Main Window';
            app.PoseViewPanel = uipanel(app.UIFigure);
            app.PoseViewPanel.Title = '                                 Pose View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configuration_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end