classdef device_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChannelConfigurationPanel      matlab.ui.container.Panel
        ReceiverPortDropDown           matlab.ui.control.DropDown
        ReceiverPortDropDownLabel      matlab.ui.control.Label
        TransmitterPortDropDown        matlab.ui.control.DropDown
        TransmitterPortDropDownLabel   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        SetupButton                    matlab.ui.control.Button
        TurnOFFWindowButton            matlab.ui.control.Button
        ExportConfigurationButton      matlab.ui.control.Button
        FormatDropDown                 matlab.ui.control.DropDown
        FormatDropDownLabel            matlab.ui.control.Label
        AcquisitionConfigurationPanel  matlab.ui.container.Panel
        PowerLabel                     matlab.ui.control.Label
        SourcePowerdBmEditField        matlab.ui.control.NumericEditField
        SourcePowerdBmEditFieldLabel   matlab.ui.control.Label
        WARNINGPowermustnotexceed15dBmLabel  matlab.ui.control.Label
        FrequencyUnitsDropDown         matlab.ui.control.DropDown
        FrequencyUnitsDropDownLabel    matlab.ui.control.Label
        IFBandwidthHzEditField         matlab.ui.control.NumericEditField
        IFBandwidthHzEditFieldLabel    matlab.ui.control.Label
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        HigherFrequencyEditField       matlab.ui.control.NumericEditField
        HigherFrequencyEditFieldLabel  matlab.ui.control.Label
        LowerFrequencyEditField        matlab.ui.control.NumericEditField
        LowerFrequencyEditFieldLabel   matlab.ui.control.Label
        ConnectivityPanel              matlab.ui.container.Panel
        DeviceIPEditField              matlab.ui.control.EditField
        DeviceIPEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        AppControlHandle;
        devPNA;
        numPoints; 
    end
    
    properties (Access = private)
        lfreq; 
        hfreq; 
        freqValue;
        IFfilterRes;
        sigPower; 
        format;
        receiverPort;
        isWindowOff = false;
    end
    
    methods (Access = private)
        
        function connectDevice(app, ipStr)
            try
                resource = "TCPIP0::" + ipStr + "::inst0::INSTR";

                app.devPNA = visadev(resource);
                app.ConnectedLabel.Text = "Device connected succesfully";
            catch ME
                app.ConnectedLabel.Text = "Device connection failed";
                uialert(app.UIFigure, ME.message, 'Advert', 'Icon', 'warning');
            end
        end
        
        function loadValues(app)
            freqUnitsScale = 1;
            switch upper(app.FrequencyUnitsDropDown.Value)
                case 'KHZ'
                    freqUnitsScale = 1e3;
                case 'MHZ'
                    freqUnitsScale = 1e6;
                case 'GHZ'
                    freqUnitsScale = 1e9;
            end
        
            app.lfreq = app.LowerFrequencyEditField.Value  * freqUnitsScale;
            app.hfreq = app.HigherFrequencyEditField.Value * freqUnitsScale;
            app.numPoints = app.PointsEditField.Value;
            app.IFfilterRes = app.IFBandwidthHzEditField.Value;
            app.sigPower  = app.SourcePowerdBmEditField.Value;  
            if (app.sigPower > 15)
                app.PowerLabel.Text = "Power must not exceed 15 dBm";
                app.PowerLabel.FontColor = 'r';
            end
            app.format = upper(app.FormatDropDown.Value);
            switch upper(app.ReceiverPortDropDown.Value)
                case 'A'
                    app.receiverPort = "AR1,1";
        
                case 'B'
                    app.receiverPort = "BR1,1";
        
                case 'P2'
                    app.receiverPort = "SR2,1";
        
                otherwise
                    app.receiverPort = "";
                    uialert(app.UIFigure, ...
                        'Invalid receiver port selection', ...
                        'Receiver Port Error','Icon','error');
            end
        end
        
        function [devPNA] = configurePNA(app, ip)
            resource = "TCPIP0::" + ip + "::inst0::INSTR";
        
            try
                devPNA = visadev(resource);
                disp("Conectado correctamente al instrumento.");
            catch ME
                warning("No se pudo conectar al instrumento:");
                fprintf("%s\n", ME.message);
                devPNA = [];
                return;
            end
        
            devPNA.Timeout = 30;
        
            writeline(devPNA, "*IDN?");
            respuesta = readline(devPNA);
            disp("Identificación: " + respuesta);
        
            freq_vec = linspace(app.lfreq, app.hfreq, app.numPoints);
        
            writeline(devPNA, 'ABOR1');
        
            writeline(devPNA, "SENS:FREQ:STAR " + num2str(freq_vec(1)));
            writeline(devPNA, "SENS:FREQ:STOP " + num2str(freq_vec(end)));
            writeline(devPNA, "SENS:SWE:POIN " + num2str(app.numPoints));
            writeline(devPNA, "SENS:SWE:TRIG:MODE SWE");
            writeline(devPNA, "SOUR1:POW " + num2str(app.sigPower));
        
            pause(0.001);
        
            writeline(devPNA, 'CALC:PAR:DEL:ALL');
        
            writeline(devPNA, "CALC:PAR:DEF 'canal_A_fase'," + app.receiverPort);
            writeline(devPNA, "CALC:PAR:SEL 'canal_A_fase'");
            writeline(devPNA, "DISP:WIND:TRAC3:FEED 'canal_A_fase'");
            writeline(devPNA, "CALC:FORM PHASe");
        
            writeline(devPNA, "CALC:PAR:DEF 'canal_A_ampli',"+ app.receiverPort);
            writeline(devPNA, "CALC:PAR:SEL 'canal_A_ampli'");
            writeline(devPNA, "DISP:WIND:TRAC2:FEED 'canal_A_ampli'");
            writeline(devPNA, "CALC:FORM " + app.format);
        
            writeline(devPNA, 'SENS:SWE:MODE SING');
        
            writeline(devPNA, "SENS:BWID:RES " + num2str(app.IFfilterRes));
        
            uialert(app.UIFigure, "PNA Configured Succesfully!", "Info");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DeviceIPEditField.Value = "169.254.90.182";
            app.UIFigure.Position = [100 100 725 430];
            app.UIFigure.Resize = 'off';
        end

        % Callback function
        function ConnectButtonPushed(app, event)
            ipStr = app.DeviceIPEditField.Value;
            app.connectDevice(ipStr);
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            app.loadValues();
            try
                clear app.devPNA
                
                [app.devPNA] = app.configurePNA(app.DeviceIPEditField.Value);
             
            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Configuration error', 'Icon','error');
            end
        end

        % Button pushed function: TurnOFFWindowButton
        function TurnOFFWindowButtonPushed(app, event)
            if isempty(app.devPNA)
                return;
            end
        
            if app.isWindowOff
                writeline(app.devPNA, "DISP:ENAB ON");
                app.TurnOFFWindowButton.Text = "Turn OFF Window";
                app.isWindowOff = false;
            else
                writeline(app.devPNA, "DISP:ENAB OFF");
                app.TurnOFFWindowButton.Text = "Turn ON Window";
                app.isWindowOff = true;
            end
        end

        % Button pushed function: ExportConfigurationButton
        function ExportConfigurationButtonPushed(app, event)
            app.AppControlHandle.devPNA = app.devPNA;
            app.AppControlHandle.PNANumPoints = app.numPoints;
            app.AppControlHandle.lowFreq = app.lfreq;
            app.AppControlHandle.highFreq = app.hfreq;
            app.AppControlHandle.IFfilterRes = app.IFfilterRes;
            app.AppControlHandle.sigPower = app.sigPower;
            app.AppControlHandle.receiverPort = app.receiverPort;
            uialert(app.UIFigure, "PNA Exported succesfully!", "Info");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectivityPanel = uipanel(app.UIFigure);
            app.ConnectivityPanel.Title = '                                                                                            Connectivity Panel';
            app.DeviceIPEditFieldLabel = uilabel(app.ConnectivityPanel);
            app.DeviceIPEditFieldLabel.Text = 'Device IP';
            app.DeviceIPEditField = uieditfield(app.ConnectivityPanel, 'text');
            app.AcquisitionConfigurationPanel = uipanel(app.UIFigure);
            app.AcquisitionConfigurationPanel.Title = '                            Acquisition Configuration Panel';
            app.LowerFrequencyEditFieldLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.LowerFrequencyEditFieldLabel.Text = 'Lower Frequency';
            app.LowerFrequencyEditField = uieditfield(app.AcquisitionConfigurationPanel, 'numeric');
            app.HigherFrequencyEditFieldLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.HigherFrequencyEditFieldLabel.Text = 'Higher Frequency';
            app.HigherFrequencyEditField = uieditfield(app.AcquisitionConfigurationPanel, 'numeric');
            app.PointsEditFieldLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.AcquisitionConfigurationPanel, 'numeric');
            app.IFBandwidthHzEditFieldLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.IFBandwidthHzEditFieldLabel.Text = 'IF Bandwidth [Hz]';
            app.IFBandwidthHzEditField = uieditfield(app.AcquisitionConfigurationPanel, 'numeric');
            app.FrequencyUnitsDropDownLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.FrequencyUnitsDropDownLabel.Text = 'Frequency Units';
            app.FrequencyUnitsDropDown = uidropdown(app.AcquisitionConfigurationPanel);
            app.FrequencyUnitsDropDown.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.FrequencyUnitsDropDown.Value = 'Hz';
            app.WARNINGPowermustnotexceed15dBmLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.WARNINGPowermustnotexceed15dBmLabel.Text = '';
            app.SourcePowerdBmEditFieldLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.SourcePowerdBmEditFieldLabel.Text = 'Source Power [dBm]';
            app.SourcePowerdBmEditField = uieditfield(app.AcquisitionConfigurationPanel, 'numeric');
            app.PowerLabel = uilabel(app.AcquisitionConfigurationPanel);
            app.PowerLabel.Text = '';
            app.ChannelConfigurationPanel = uipanel(app.UIFigure);
            app.ChannelConfigurationPanel.Title = '                       Channel Configuration Panel';
            app.FormatDropDownLabel = uilabel(app.ChannelConfigurationPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.ChannelConfigurationPanel);
            app.FormatDropDown.Items = {'MLOG', 'test1', 'test2'};
            app.FormatDropDown.Value = 'MLOG';
            app.Panel = uipanel(app.ChannelConfigurationPanel);
            app.ExportConfigurationButton = uibutton(app.Panel, 'push');
            app.ExportConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportConfigurationButtonPushed, true);
            app.ExportConfigurationButton.Text = 'Export Configuration';
            app.TurnOFFWindowButton = uibutton(app.Panel, 'push');
            app.TurnOFFWindowButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOFFWindowButtonPushed, true);
            app.TurnOFFWindowButton.Text = 'Turn OFF Window';
            app.SetupButton = uibutton(app.Panel, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.Label = uilabel(app.ChannelConfigurationPanel);
            app.Label.Text = '';
            app.TransmitterPortDropDownLabel = uilabel(app.ChannelConfigurationPanel);
            app.TransmitterPortDropDownLabel.Text = 'Transmitter Port';
            app.TransmitterPortDropDown = uidropdown(app.ChannelConfigurationPanel);
            app.TransmitterPortDropDown.Items = {'P1', 'Option 2', 'Option 3', 'Option 4'};
            app.TransmitterPortDropDown.Value = 'P1';
            app.ReceiverPortDropDownLabel = uilabel(app.ChannelConfigurationPanel);
            app.ReceiverPortDropDownLabel.Text = 'Receiver Port';
            app.ReceiverPortDropDown = uidropdown(app.ChannelConfigurationPanel);
            app.ReceiverPortDropDown.Items = {'A', 'B', 'P2'};
            app.ReceiverPortDropDown.Value = 'A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = device_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end