classdef environment_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExporttoMainWindowButton   matlab.ui.control.Button
        EnvironmentViewPanel       matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        ClearButton                matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        EnvIdLabel                 matlab.ui.control.Label
        SelectedenvironmentLabel   matlab.ui.control.Label
        CreateObjectPanel          matlab.ui.container.Panel
        PosZEditField              matlab.ui.control.NumericEditField
        PositionzmLabel            matlab.ui.control.Label
        PosYEditField              matlab.ui.control.NumericEditField
        PositionymLabel            matlab.ui.control.Label
        PosXEditField              matlab.ui.control.NumericEditField
        PositionxmLabel            matlab.ui.control.Label
        LongZEditField             matlab.ui.control.NumericEditField
        LengthzmLabel              matlab.ui.control.Label
        LongYEditField             matlab.ui.control.NumericEditField
        LengthymLabel              matlab.ui.control.Label
        AddButton                  matlab.ui.control.Button
        LongXEditField             matlab.ui.control.NumericEditField
        LengthxmLabel              matlab.ui.control.Label
        EnvironmentSetupPanel      matlab.ui.container.Panel
        LoadButton                 matlab.ui.control.Button
        EnvironmentIdSpinner       matlab.ui.control.Spinner
        EnvironmentIdSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        selectedEnvId; % Selected simulation environment id, Integer 
    end
    
    properties (Access = public)
        AppControlHandle;
        currentWindowEnv = {}; % Current selected simulation environment. Cells array with collision boxes
        robot;
    end
    
    methods (Access = private)
        
        function loadObject(app)
            lengthX = app.LongXEditField.Value;
            lengthY = app.LongYEditField.Value;
            lengthZ = app.LongZEditField.Value;
            poseX = app.PosXEditField.Value;
            poseY = app.PosYEditField.Value;
            poseZ = app.PosZEditField.Value;
            
            objectPose = [poseX, poseY, poseZ];

            collisionObject = collisionBox(lengthX, lengthY, lengthZ);
            collisionObject.Pose = trvec2tform(objectPose);

            app.currentWindowEnv{end+1} = collisionObject;
        end
        
        function writeEnvFile(app)
            fileName = sprintf('env_%d.mat', app.selectedEnvId);
            fileRoute = fullfile("environments", fileName);
            env.id = app.selectedEnvId;
            env.objects = app.currentWindowEnv;
            save(fileRoute, 'env');
        end
        
        function readEnvFile(app)
            app.currentWindowEnv = {};
            fileName = sprintf('env_%d.mat', app.selectedEnvId);
            fileRoute = fullfile("environments", fileName);
            
            [data, status] = Utils.readFile(fileRoute);
            if (~status)
                app.currentWindowEnv = data.env.objects;
            else
                msg = sprintf("ERROR: Can't read the file %s", char(fileRoute));
                uialert(app.UIFigure, msg, "File read error");
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 500 430];
            app.UIFigure.Resize = 'off';
            app.EnvironmentIdSpinner.Limits = [0, 100];
            app.selectedEnvId = 0;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.selectedEnvId = app.EnvironmentIdSpinner.Value;
            app.EnvIdLabel.Text = num2str(app.selectedEnvId);
            app.readEnvFile();
            app.robot = app.AppControlHandle.robot;
            VisualUtils.showRobotEnvPanel(app.EnvironmentViewPanel, app.robot, app.AppControlHandle.robotCurrentConfig, app.currentWindowEnv);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.loadObject();
            app.robot = app.AppControlHandle.robot;
            VisualUtils.showRobotEnvPanel(app.EnvironmentViewPanel, app.robot, app.AppControlHandle.robotCurrentConfig, app.currentWindowEnv);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.writeEnvFile();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.currentWindowEnv = {};
            app.writeEnvFile();
            app.robot = app.AppControlHandle.robot;
            VisualUtils.showRobotEnvPanel(app.EnvironmentViewPanel, app.robot, app.AppControlHandle.robotCurrentConfig, app.currentWindowEnv);
        end

        % Button pushed function: ExporttoMainWindowButton
        function ExporttoMainWindowButtonPushed(app, event)
            app.AppControlHandle.receiveEnvFromMenu(app.currentWindowEnv);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentSetupPanel = uipanel(app.UIFigure);
            app.EnvironmentSetupPanel.Title = '                                                  Environment Setup';
            app.EnvironmentIdSpinnerLabel = uilabel(app.EnvironmentSetupPanel);
            app.EnvironmentIdSpinnerLabel.Text = 'Environment Id';
            app.EnvironmentIdSpinner = uispinner(app.EnvironmentSetupPanel);
            app.LoadButton = uibutton(app.EnvironmentSetupPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CreateObjectPanel = uipanel(app.UIFigure);
            app.CreateObjectPanel.Title = '                     Create Object';
            app.LengthxmLabel = uilabel(app.CreateObjectPanel);
            app.LengthxmLabel.Text = 'Length z (m)';
            app.LongXEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.AddButton = uibutton(app.CreateObjectPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.LengthymLabel = uilabel(app.CreateObjectPanel);
            app.LengthymLabel.Text = 'Length x (m)';
            app.LongYEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.LengthzmLabel = uilabel(app.CreateObjectPanel);
            app.LengthzmLabel.Text = 'Length y (m)';
            app.LongZEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.PositionxmLabel = uilabel(app.CreateObjectPanel);
            app.PositionxmLabel.Text = 'Position z (m)';
            app.PosXEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.PositionymLabel = uilabel(app.CreateObjectPanel);
            app.PositionymLabel.Text = 'Position x (m)';
            app.PosYEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.PositionzmLabel = uilabel(app.CreateObjectPanel);
            app.PositionzmLabel.Text = 'Position y (m)';
            app.PosZEditField = uieditfield(app.CreateObjectPanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.SelectedenvironmentLabel = uilabel(app.Panel);
            app.SelectedenvironmentLabel.Text = 'Selected environment:';
            app.EnvIdLabel = uilabel(app.Panel);
            app.EnvIdLabel.Text = '';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.EnvironmentViewPanel = uipanel(app.UIFigure);
            app.EnvironmentViewPanel.Title = '                      Environment View';
            app.ExporttoMainWindowButton = uibutton(app.UIFigure, 'push');
            app.ExporttoMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoMainWindowButtonPushed, true);
            app.ExporttoMainWindowButton.Text = 'Export to Main Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = environment_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end