classdef planar_sweep_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SimulationPanel             matlab.ui.container.Panel
        PlanarSweepParametersPanel  matlab.ui.container.Panel
        YMaxValuemmEditField        matlab.ui.control.NumericEditField
        YMaxValuemmEditFieldLabel   matlab.ui.control.Label
        XMaxValuemmEditField        matlab.ui.control.NumericEditField
        XMaxValuemmEditFieldLabel   matlab.ui.control.Label
        YRotationEditField          matlab.ui.control.NumericEditField
        ZRotationLabel              matlab.ui.control.Label
        XRotationEditField          matlab.ui.control.NumericEditField
        YRotationLabel              matlab.ui.control.Label
        ZRotationEditField_2        matlab.ui.control.NumericEditField
        XRotationLabel              matlab.ui.control.Label
        YOffsetmmEditField          matlab.ui.control.NumericEditField
        YOffsetmmEditFieldLabel     matlab.ui.control.Label
        XOffsetmmEditField          matlab.ui.control.NumericEditField
        XOffsetmmEditFieldLabel     matlab.ui.control.Label
        ZOffsetmmEditField_2        matlab.ui.control.NumericEditField
        ZOffsetmmEditField_2Label   matlab.ui.control.Label
        CreateGridButton            matlab.ui.control.Button
        NyPointsEditField           matlab.ui.control.NumericEditField
        NyPointsEditFieldLabel      matlab.ui.control.Label
        NxPointsEditField           matlab.ui.control.NumericEditField
        NxPointsEditFieldLabel      matlab.ui.control.Label
        YMinValuemmEditField        matlab.ui.control.NumericEditField
        YMinValuemmEditFieldLabel   matlab.ui.control.Label
        XMinValuemmEditField        matlab.ui.control.NumericEditField
        XMinValuemmEditFieldLabel   matlab.ui.control.Label
        ZDistancemmEditField        matlab.ui.control.NumericEditField
        ZDistancemmEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        robot;
        env; 
        robotCurrentConfig; 
        robotDesiredConfig;
        AppControlHandle;
        validationDist;
        simulationSecs;
        operationMode;
        robotRTDEClient;
        robotPort;
        acc;
        speed;
        toleranceErrorDegrees;
        fi = deg2rad(90);
        psi = deg2rad(90);
        theta = deg2rad(180);
        optitrackNatnetclient;
        devPNA;
        weights;
    end
    
    properties (Access = private)
        Xdist;
        YminValue;
        YmaxValue;
        ZminValue;
        ZmaxValue;
        Ny;
        Nz;
        samplingStep;
        Xoffset;
        Yoffset;
        Zoffset;
        XrotValue;
        YrotValue; 
        ZrotValue; 
    end
    
    methods (Access = private)

        function empty = isPointConfigEmpty(app)
            NyPoints = app.NxPointsEditField.Value;
            NzPoints = app.NyPointsEditField.Value;
            empty = ((NyPoints == 0) && (NzPoints == 0));
        end

        function empty = isSamplingStepConfigEmpty(app)
            samplingValue = app.SamplingStepmmEditField.Value;
            empty = (samplingValue == 0);
        end

        function handleSweepParameters(app)
            app.Xdist = app.ZDistancemmEditField.Value / 1000;
            
            app.YminValue = app.XMinValuemmEditField.Value / 1000;
            app.YmaxValue = app.XMaxValuemmEditField.Value / 1000;
            app.ZminValue = app.YMinValuemmEditField.Value / 1000;
            app.ZmaxValue = app.YMaxValuemmEditField.Value / 1000;

            app.Ny = app.NxPointsEditField.Value;
            app.Nz = app.NyPointsEditField.Value;

            app.Xoffset = app.ZOffsetmmEditField_2.Value / 1000;
            app.Yoffset = app.XOffsetmmEditField.Value / 1000;
            app.Zoffset = app.YOffsetmmEditField.Value / 1000;


            app.XrotValue = deg2rad(app.ZRotationEditField_2.Value);
            app.YrotValue = deg2rad(app.XRotationEditField.Value);
            app.ZrotValue = deg2rad(app.YRotationEditField.Value);
        end
        
        function [rotPointsX, rotPointsY, rotPointsZ] = makeRotationMatrix(app)
        
            Y_rangeMatrix = [app.YminValue, app.YmaxValue];
            Z_rangeMatrix = [app.ZminValue, app.ZmaxValue];


            y = linspace(Y_rangeMatrix(1), Y_rangeMatrix(2), app.Ny);
            z = linspace(Z_rangeMatrix(1), Z_rangeMatrix(2), app.Nz);

            [Y1, Z1] = meshgrid(y, z);

            Z1(:, 1:2:end) = flipud(Z1(:,1:2:end));

            Y = Y1(:);
            X1 = ones(size(Y)) * app.Xdist;
            X = X1(:);
            Z = Z1(:);

            R_x = [1 0 0; 0 cos(app.XrotValue) -sin(app.XrotValue); 0 sin(app.XrotValue) cos(app.XrotValue)];
            R_y = [cos(app.YrotValue) 0 sin(app.YrotValue); 0 1 0; -sin(app.YrotValue) 0 cos(app.YrotValue)];
            R_z = [cos(app.ZrotValue) -sin(app.ZrotValue) 0; sin(app.ZrotValue) cos(app.ZrotValue) 0; 0 0 1];

            R = R_z * R_y * R_x;

            originalPoints = [X, Y, Z];
            rotatedPoints = (R * originalPoints')';

            rotPointsX = rotatedPoints(:,1) + app.Xoffset;
            rotPointsY = rotatedPoints(:, 2) + app.Yoffset;
            rotPointsZ = rotatedPoints(:, 3) + app.Zoffset;
        end


        function plotCartesianPath(app)      
            [rotPointsX, rotPointsY, rotPointsZ] = app.makeRotationMatrix();


            delete(findall(app.SimulationPanel, 'Type', 'axes'));
            ax = axes('Parent', app.SimulationPanel);
            
            hold(ax, 'on');
            grid(ax, 'on');
            axis(ax, 'equal');
            xlabel(ax, 'Z (m)');
            ylabel(ax, 'X (m)');
            zlabel(ax, 'Y (m)');
            title(ax, 'Planar sweep grid');
        
            for k = 1:numel(app.env)
                show(app.env{k}, 'Parent', ax);
            end
            
            show(app.robot, app.robotCurrentConfig, 'Parent', ax, 'PreservePlot', true);
            xlim(ax, 'auto');
            ylim(ax, 'auto');
            zlim(ax, 'auto');
            scatter3(ax, rotPointsX, rotPointsY, rotPointsZ, 40, 'filled', 'MarkerFaceColor', [0.8 0.2 0.2]);
            
            view(ax, 3);
            hold(ax, 'off');
        end

        
    end
    
    methods (Access = public)
        
        function setUp(app, robot, robotCurrentConfig, env, validationDist, simSecs, opMode, ...
                robotRTDEClient, acc, speed, robotPort, tolErrDeg, weights)
            app.robot = robot;
            app.robotCurrentConfig = robotCurrentConfig;
            app.env = env;
            app.validationDist = validationDist;
            app.simulationSecs = simSecs;
            app.operationMode = opMode;
            app.robotRTDEClient = robotRTDEClient;
            app.acc = acc;
            app.speed = speed;
            app.robotPort = robotPort;
            app.toleranceErrorDegrees = tolErrDeg;
            app.weights = weights;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 890 420];
            app.UIFigure.Resize = 'off';
        end

        % Button pushed function: CreateGridButton
        function CreateGridButtonPushed(app, event)
            app.handleSweepParameters();
            app.plotCartesianPath();

            if app.isPointConfigEmpty() && app.isSamplingStepConfigEmpty()
                uialert(app.UIFigure, ...
                    'Sweep data is empty', ...
                    'Error');
                return;
            end
        
            sweepData.Xdist = app.Xdist;
            sweepData.Ymin = app.YminValue;
            sweepData.Ymax = app.YmaxValue;
            sweepData.Zmin = app.ZminValue;
            sweepData.Zmax = app.ZmaxValue;
        
            sweepData.Ny = app.Ny;
            sweepData.Nz = app.Nz;
            sweepData.samplingStep = app.samplingStep;
        
            sweepData.Xoffset = app.Xoffset;
            sweepData.Yoffset = app.Yoffset;
            sweepData.Zoffset = app.Zoffset;
        
            sweepData.rotX = app.XrotValue;
            sweepData.rotY = app.YrotValue;
            sweepData.rotZ = app.ZrotValue;
        
            app.AppControlHandle.setPlanarSweepData(sweepData);
        
            uialert(app.UIFigure, ...
                'Planar Sweep Grid Created!', ...
                'Info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlanarSweepParametersPanel = uipanel(app.UIFigure);
            app.PlanarSweepParametersPanel.Title = '                 Planar Sweep Parameters';
            app.ZDistancemmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.ZDistancemmEditFieldLabel.Text = 'Z Distance [mm]';
            app.ZDistancemmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.XMinValuemmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.XMinValuemmEditFieldLabel.Text = 'X Min Value [mm]';
            app.XMinValuemmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.YMinValuemmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.YMinValuemmEditFieldLabel.Text = 'Y Min Value [mm]';
            app.YMinValuemmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.NxPointsEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.NxPointsEditFieldLabel.Text = 'Nx Points';
            app.NxPointsEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.NyPointsEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.NyPointsEditFieldLabel.Text = 'Ny Points';
            app.NyPointsEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.CreateGridButton = uibutton(app.PlanarSweepParametersPanel, 'push');
            app.CreateGridButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGridButtonPushed, true);
            app.CreateGridButton.Text = 'Create Grid';
            app.ZOffsetmmEditField_2Label = uilabel(app.PlanarSweepParametersPanel);
            app.ZOffsetmmEditField_2Label.Text = 'Z Offset [mm]';
            app.ZOffsetmmEditField_2 = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.XOffsetmmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.XOffsetmmEditFieldLabel.Text = 'X Offset [mm]';
            app.XOffsetmmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.YOffsetmmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.YOffsetmmEditFieldLabel.Text = 'Y Offset [mm]';
            app.YOffsetmmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.XRotationLabel = uilabel(app.PlanarSweepParametersPanel);
            app.XRotationLabel.Text = 'Z Rotation [º]';
            app.ZRotationEditField_2 = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.YRotationLabel = uilabel(app.PlanarSweepParametersPanel);
            app.YRotationLabel.Text = 'X Rotation [º]';
            app.XRotationEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.ZRotationLabel = uilabel(app.PlanarSweepParametersPanel);
            app.ZRotationLabel.Text = 'Y Rotation [º]';
            app.YRotationEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.XMaxValuemmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.XMaxValuemmEditFieldLabel.Text = 'X Max Value [mm]';
            app.XMaxValuemmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.YMaxValuemmEditFieldLabel = uilabel(app.PlanarSweepParametersPanel);
            app.YMaxValuemmEditFieldLabel.Text = 'Y Max Value [mm]';
            app.YMaxValuemmEditField = uieditfield(app.PlanarSweepParametersPanel, 'numeric');
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = '                                               Simulation Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = planar_sweep_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end